classdef ballsimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        BallScrewSimulationPanel  matlab.ui.container.Panel
        SelectButton              matlab.ui.control.Button
        ImportmatfileLabel        matlab.ui.control.Label
        TabGroup                  matlab.ui.container.TabGroup
        MeshandintersectpointTab  matlab.ui.container.Tab
        MeshInteractionAxes       matlab.ui.control.UIAxes
        GradientTemperatureTab    matlab.ui.container.Tab
        GradientTemperatureAxes   matlab.ui.control.UIAxes
        WithCoordinateButton      matlab.ui.control.Button
        Cut_XEditFieldLabel       matlab.ui.control.Label
        Cut_XEditField            matlab.ui.control.NumericEditField
        Cut_YEditFieldLabel       matlab.ui.control.Label
        Cut_YEditField            matlab.ui.control.NumericEditField
        Show                      matlab.ui.control.Button
        GradientCutAxes           matlab.ui.control.UIAxes
        XdisplacementTab          matlab.ui.container.Tab
        XAxes                     matlab.ui.control.UIAxes
        TimeEditFieldLabel        matlab.ui.control.Label
        TimeEditField             matlab.ui.control.NumericEditField
        ClickButton               matlab.ui.control.Button
        YdisplacementTab          matlab.ui.container.Tab
        YAxes                     matlab.ui.control.UIAxes
        AnimationTab              matlab.ui.container.Tab
        Animate                   matlab.ui.control.UIAxes
        StartButton               matlab.ui.control.Button
        FileName                  matlab.ui.control.Label
    end

    
    properties (Access = public)
        time_in; % Description
        Cut_X;
        Cut_Y;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: MeshandintersectpointTab, SelectButton
        function ChoosefileSelectButtonPushed(app, event)
           
            app.time_in = 20;
            importFile(app,event);
            PlotMesh(app,event);
            GradientTemperature(app,event);
            xDisplacement(app,event);
            yDisplacement(app,event);
            
            
%         Parameters = evalin('base', 'Parameters');  
%         Initial = evalin('base', 'Initial');  
%         ThermalModel = evalin('base', 'ThermalModel'); 
%         ThermalGradian = evalin('base', 'ThermalGradian'); 
%         ThermalStressResult = evalin('base', 'ThermalStressResult'); 
%         Thermalexpansion = evalin('base', 'Thermalexpansion'); 
%         Tempreture = evalin('base', 'Tempreture'); 
%         F_preload = evalin('base', 'F_preload'); 
%       
        
% ------------------- plot Mesh and intersect point ------------------------
         
%         fig_mesh = figure('visible','off');
%         Basic = createpde;
%         Basic.geometryFromMesh(Parameters.Point2D.Points', Parameters.Point2D.ConnectivityList');
%         for k = 1:Initial.revolutions
%         N_ID(k,1) = findNodes(Basic.Mesh,'nearest',[Parameters.EndPointRadius(1,k);Parameters.EndPointRadius(2,k)]);
%         En(k,:) = findElements(Basic.Mesh,'radius',[Parameters.EndPointRadius(1,k) Parameters.EndPointRadius(2,k)],Initial.meshSize);
%         end
%         pdegplot(ThermalModel,'EdgeLabels','on')
%         pdemesh(ThermalModel) 
%         hold on
%         plot(Basic.Mesh.Nodes(1,N_ID),Basic.Mesh.Nodes(2,N_ID),'ok','MarkerFaceColor','g')
%         pdemesh(Basic.Mesh.Nodes,Basic.Mesh.Elements(:,En),'EdgeColor','blue')
%         
%         
%         axes_to_be_copied = findobj(fig_mesh,'type','axes'); 
%         chilred_to_be_copied = get(axes_to_be_copied,'children'); 
% %         [az,el] = view; 
%         copyobj(chilred_to_be_copied,app.MeshInteractionAxes); 
%         set(app.MeshInteractionAxes,'Xlim',get(axes_to_be_copied,'XLim')); 
%         set(app.MeshInteractionAxes,'Ylim',get(axes_to_be_copied,'YLim'));
%         set(app.MeshInteractionAxes,'Zlim',get(axes_to_be_copied,'ZLim')); 
%         app.MeshInteractionAxes.Title.String = 'Mesh with Triangular Elements';
        
% ----------------- Gradian Tempereture after loop -------------------------
%         fig_grad = figure('visible','off'); 
%         Nf_right = findNodes(ThermalModel.Mesh,'region','Edge',1);
%         Nf_up = findNodes(ThermalModel.Mesh,'region','Edge',8);
%         Nf_left = findNodes(ThermalModel.Mesh,'region','Edge',20);
%         [qx,qy] = evaluateHeatFlux(ThermalGradian);
%         
%         pdeplot(ThermalModel,'XYData',Tempreture(:,end), ...
%                              'Contour','on',...
%                              'FlowData',[qx(:,end),qy(:,end)], ...
%                              'ColorMap','jet')
%         
%         
%         
%   
%         
%         
%         axes_to_be_copied_grad = findobj(fig_grad,'type','axes'); 
%         chilred_to_be_copied_grad = get(axes_to_be_copied_grad,'children'); 
% %         [az,el] = view; 
%         copyobj(chilred_to_be_copied_grad,app.GradientTemperatureAxes); 
%         set(app.GradientTemperatureAxes,'Xlim',get(axes_to_be_copied_grad,'XLim')); 
%         set(app.GradientTemperatureAxes,'Ylim',get(axes_to_be_copied_grad,'YLim'));
%         set(app.GradientTemperatureAxes,'Zlim',get(axes_to_be_copied_grad,'ZLim')); 
%         app.GradientTemperatureAxes.Title.String = 'Temperature Distribution and Heat Flux';
%         grad = colorbar(app.GradientTemperatureAxes)
%         colormap(app.GradientTemperatureAxes, jet)
%         
        
         % with ginput


%         tlist = 1:1:size(Tempreture,2);
%         plot(app.GradientTemperatureWithInputAxes, tlist(1:size(Tempreture,2)), Tempreture(node_Cut,:))  
        
%         axes_to_be_copied_grad_with_input = findobj(fig_grad,'type','axes'); 
%         chilred_to_be_copied_grad_with_input = get(axes_to_be_copied_grad_with_input,'paren'); 
%         [az,el] = view; 
%         copyobj(chilred_to_be_copied_grad_with_input,app.GradientTemperatureWithInputAxes); 
%         set(app.GradientTemperatureWithInputAxes,'Xlim',get(axes_to_be_copied,'XLim')) 
%         set(app.GradientTemperatureWithInputAxes,'Ylim',get(axes_to_be_copied,'YLim')) 
%         set(app.GradientTemperatureWithInputAxes,'Zlim',get(axes_to_be_copied,'ZLim')) 
        

% --------------------- X displacememnt --------------
%         fig_x = figure('visible','off'); 
%         app.time_in = 20;  % default time_in = end
%         disp(app.time_in)
%         model = createpde('structural','static-planestress');
%         pdeplot(ThermalModel, 'XYData',Thermalexpansion(:,app.time_in),'ColorMap','jet')
%         
%         
%         axes_to_be_copied_x = findobj(fig_x,'type','axes'); 
%         chilred_to_be_copied_x = get(axes_to_be_copied_x,'children'); 
% %         [az,el] = view; 
%         copyobj(chilred_to_be_copied_x,app.XAxes); 
%         set(app.XAxes,'Xlim',get(axes_to_be_copied_x,'XLim')); 
%         set(app.XAxes,'Ylim',get(axes_to_be_copied_x,'YLim'));
%         set(app.XAxes,'Zlim',get(axes_to_be_copied_x,'ZLim')); 
%         app.XAxes.Title.String = 'x-displacement';
%          x = colorbar(app.XAxes)
%         colormap(app.XAxes, jet)
        
% --------------------- Y displacememnt --------------
%         fig_y = figure('visible','off');
%         pdeplot(ThermalModel, 'XYData',ThermalStressResult.Displacement.uy,'ColorMap','jet')
%       
%         
%         axes_to_be_copied_y = findobj(fig_y,'type','axes'); 
%         chilred_to_be_copied_y = get(axes_to_be_copied_y,'children'); 
% %         [az,el] = view; 
%         copyobj(chilred_to_be_copied_y,app.YAxes); 
%         set(app.YAxes,'Xlim',get(axes_to_be_copied_y,'XLim')); 
%         set(app.YAxes,'Ylim',get(axes_to_be_copied_y,'YLim'));
%         app.YAxes.Title.String = 'y-displacement';
%         y = colorbar(app.YAxes)
%         colormap(app.YAxes, jet)
        
      
        end

        % Button pushed function: ClickButton
        function ClickButtonPushed(app, event)

            app.time_in = app.TimeEditField.Value;
            xDisplacement(app,event);
            
        end

        % Button pushed function: WithCoordinateButton
        function WithCoordinateButtonPushed(app, event)
            app.Cut_XEditField.Visible = 'on';
            app.Cut_YEditField.Visible = 'on';
            app.Cut_XEditFieldLabel.Visible = 'on';
            app.Cut_YEditFieldLabel.Visible = 'on';
            app.Show.Visible = 'on';
            app.GradientCutAxes.Visible = 'on';
           
%             app.GradientCutAxes.Visible = 'on'
            
            
        end

        % Value changed function: Cut_XEditField
        function Cut_XEditFieldValueChanged(app, event)
            value = app.Cut_XEditField.Value;
            
        end

        % Button pushed function: Show
        function ShowButtonPushed(app, event)
            app.Cut_X = app.Cut_XEditField.Value;
            app.Cut_Y = app.Cut_YEditField.Value;
            withCoordinate(app,event);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            Animate(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BallScrewSimulationPanel = uipanel(app.UIFigure);
            app.BallScrewSimulationPanel.Title = 'Ball Screw Simulation';
            app.SelectButton = uibutton(app.BallScrewSimulationPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefileSelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.ImportmatfileLabel = uilabel(app.BallScrewSimulationPanel);
            app.ImportmatfileLabel.Text = 'Import .mat file ';
            app.TabGroup = uitabgroup(app.BallScrewSimulationPanel);
            app.MeshandintersectpointTab = uitab(app.TabGroup);
            app.MeshandintersectpointTab.SizeChangedFcn = createCallbackFcn(app, @ChoosefileSelectButtonPushed, true);
            app.MeshandintersectpointTab.Title = 'Mesh and intersect point';
            app.MeshInteractionAxes = uiaxes(app.MeshandintersectpointTab);
            title(app.MeshInteractionAxes, 'Title')
            xlabel(app.MeshInteractionAxes, 'X')
            ylabel(app.MeshInteractionAxes, 'Y')
            app.GradientTemperatureTab = uitab(app.TabGroup);
            app.GradientTemperatureTab.Title = 'Gradient Temperature';
            app.GradientTemperatureAxes = uiaxes(app.GradientTemperatureTab);
            title(app.GradientTemperatureAxes, 'Title')
            xlabel(app.GradientTemperatureAxes, 'X')
            ylabel(app.GradientTemperatureAxes, 'Y')
            app.WithCoordinateButton = uibutton(app.GradientTemperatureTab, 'push');
            app.WithCoordinateButton.ButtonPushedFcn = createCallbackFcn(app, @WithCoordinateButtonPushed, true);
            app.WithCoordinateButton.Text = 'With Coordinate';
            app.Cut_XEditFieldLabel = uilabel(app.GradientTemperatureTab);
            app.Cut_XEditFieldLabel.Visible = 'off';
            app.Cut_XEditFieldLabel.Text = 'Cut_X';
            app.Cut_XEditField = uieditfield(app.GradientTemperatureTab, 'numeric');
            app.Cut_XEditField.ValueChangedFcn = createCallbackFcn(app, @Cut_XEditFieldValueChanged, true);
            app.Cut_XEditField.Visible = 'off';
            app.Cut_YEditFieldLabel = uilabel(app.GradientTemperatureTab);
            app.Cut_YEditFieldLabel.Visible = 'off';
            app.Cut_YEditFieldLabel.Text = 'Cut_Y';
            app.Cut_YEditField = uieditfield(app.GradientTemperatureTab, 'numeric');
            app.Cut_YEditField.Visible = 'off';
            app.Show = uibutton(app.GradientTemperatureTab, 'push');
            app.Show.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.Show.Visible = 'off';
            app.GradientCutAxes = uiaxes(app.GradientTemperatureTab);
            title(app.GradientCutAxes, 'Title')
            xlabel(app.GradientCutAxes, 'X')
            ylabel(app.GradientCutAxes, 'Y')
            app.GradientCutAxes.Visible = 'off';
            app.XdisplacementTab = uitab(app.TabGroup);
            app.XdisplacementTab.Title = 'X displacement';
            app.XAxes = uiaxes(app.XdisplacementTab);
            title(app.XAxes, 'Title')
            xlabel(app.XAxes, 'X')
            ylabel(app.XAxes, 'Y')
            app.TimeEditFieldLabel = uilabel(app.XdisplacementTab);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.XdisplacementTab, 'numeric');
            app.TimeEditField.Value = 20;
            app.ClickButton = uibutton(app.XdisplacementTab, 'push');
            app.ClickButton.ButtonPushedFcn = createCallbackFcn(app, @ClickButtonPushed, true);
            app.ClickButton.Text = 'Click';
            app.YdisplacementTab = uitab(app.TabGroup);
            app.YdisplacementTab.Title = 'Y displacement';
            app.YAxes = uiaxes(app.YdisplacementTab);
            title(app.YAxes, 'Title')
            xlabel(app.YAxes, 'X')
            ylabel(app.YAxes, 'Y')
            app.AnimationTab = uitab(app.TabGroup);
            app.AnimationTab.Title = 'Animation';
            app.Animate = uiaxes(app.AnimationTab);
            title(app.Animate, 'Title')
            xlabel(app.Animate, 'X')
            ylabel(app.Animate, 'Y')
            app.StartButton = uibutton(app.AnimationTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.FileName = uilabel(app.BallScrewSimulationPanel);
            app.FileName.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ballsimulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end